classdef DatmasterPreference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatmasterSetupUIFigure      matlab.ui.Figure
        CurrentStausEditFieldLabel  matlab.ui.control.Label
        CurrentStausEditField       matlab.ui.control.EditField
        TextArea                    matlab.ui.control.TextArea
        RecheckDependenciesButton   matlab.ui.control.Button
        InstallAllButton            matlab.ui.control.Button
        GitButton                   matlab.ui.control.Button
        GitLamp                     matlab.ui.control.Lamp
        PythonButton                matlab.ui.control.Button
        PythonLamp                  matlab.ui.control.Lamp
        DatamasterButton            matlab.ui.control.Button
        DatmasterLamp               matlab.ui.control.Lamp
        PintButton                  matlab.ui.control.Button
        PintLamp                    matlab.ui.control.Lamp
        NumpyButton                 matlab.ui.control.Button
        NumpyLamp                   matlab.ui.control.Lamp
        CheckforUpdatesButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        function Value = getKeyValue(app, filename, key)
            
            %Open File
            fid = fopen(filename);
            
            %Read in line by line
            while ~feof(fid)
                lineText = fgetl(fid);
                
                %Check for key
                if regexpi(lineText, sprintf('%s=', key))
                    %Extact Value
                    Value = regexpi(lineText, '=(.+)', 'tokens');
                    Value = Value{:}{:};
                    break
                end
            end
            
            %Close Config File
            fclose(fid);
        end
            
    end
        

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                %Check for Configuration File
                configFile = fullfile(Datamaster.getPath, 'config.ini');
                if exist(configFile, 'file')
                    
                end
                
                %Check for Dependencies
                %RecheckDependenciesFun(app, [])
                
                %Display Warning for non PC
                if isunix
                    warndlg('Mac and *Nix systems are supported but untested. Results may very');
                end
                
        end

        % Button pushed function: BrowseButton_Datastore
        function MasterDirectoryBrowse(app, event)
                %Open UI to Select MasterDirectory
                [filename, path] = uigetfile('*.sqlite', 'Select the Master Directory',...
                    app.DatastoreDirectoryEditField.Value);
                
                if filename ~= 0
                    app.MasterDirectoryEditField.Value = fullfile(path, filename);
                end
        end

        % Callback function
        function DatastoreBrowse(app, event)
                %Open UI to Select Datastore
                path = uigetdir(app.DatastoreDirectoryEditField.Value);
                
                if path ~= 0
                    app.DatastoreDirectoryEditField.Value = path;
                end
        end

        % Button pushed function: RecheckDependenciesButton
        function RecheckDependenciesFun(app, event)
                %Create Status Struc
                status = struct('git', false, 'Datmaster', false, 'python', false',...
                    'pint', false, 'numpy', false);
                
                %% Check for git
                response = system('git');
                
                if response == 1
                    %Turn Lamp Green and disable button
                    app.GitLamp.Color = 'g';
                    app.GitButton.Enable = 'off';
                    status.git = true;
                else
                    app.GitLamp.Color = 'y';
                end
                
                %% Check for Datamaster
                if ~isempty(which('Datamaster'))
                    %Turn Lamp Green and disable button
                    app.DatmasterLamp.Color = 'g';
                    app.DatamasterButton.Enable = 'off';
                    status.Datmaster = true;
                elseif status.git
                    app.DatmasterLamp.Color = 'y';
                else
                    app.DatmasterLamp.Color = 'r';
                end
                
                %% Check for Python
                if ~isempty(pyversion)
                    %Turn Lamp Green and disable button
                    app.PythonLamp.Color = 'g';
                    app.PythonButton.Enable = 'off';
                    status.python = true;
                else
                    app.PythonLamp.Color = 'y';
                end
                
                %% Check Pint
                try
                    check = ~isempty(py.pint.UnitRegistry);
                catch
                    check = false;
                    
                end
                
                if check
                    %Turn Lamp Green and disable button
                    app.PintLamp.Color = 'g';
                    app.PintButton.Enable = 'off';
                    status.pint = true;
                elseif status.python
                    app.PintLamp.Color = 'y';
                    app.PintButton.Enable = 'on';
                else
                    app.PintLamp.Color = 'r';
                    app.PintButton.Enable = 'off';
                end
                
                %% Check Numpy
                try
                    check = ~isempty(py.numpy.array(1));
                catch
                    check = false;
                end
                
                if check
                    %Turn Lamp Green and disable button
                    app.NumpyLamp.Color = 'g';
                    app.NumpyButton.Enable = 'off';
                    status.numpy = true;
                elseif status.python
                    app.NumpyLamp.Color = 'y';
                    app.NumpyButton.Enable = 'on';
                else
                    app.NumpyLamp.Color = 'r';
                    app.NumpyButton.Enable = 'off';
                end
        end

        % Button pushed function: GitButton
        function GitButtonPushed(app, event)
                %Select Download URL based on OS
                if ismac
                    web('https://git-scm.com/download/mac')
                    return
                elseif isunix
                    web('https://git-scm.com/download/linux')
                    return                
                elseif ispc
                    url = 'https://github.com/git-for-windows/git/releases/download/v2.11.0.windows.1/Git-2.11.0-64-bit.exe';
                else
                    warndlg('Platform is not supported')
                    return
                end
                
                %Notify User
                approve = questdlg('This will download and install the latest version of git from git-scm.com',...
                    'Do you want to proceed?',...
                    'Yes', 'No', 'No');
                
                if strcmp(approve, 'Yes')
                    app.CurrentStausEditField.Value = 'Downloading Git Installer';
                    gitinstaler = websave(tempname, url);
                    
                    app.CurrentStausEditField.Value = 'Installing Git';
                    system(sprintf('%s /SILENT', gitinstaler));
                    
                    app.CurrentStausEditField.Value = '';
                end
                
                RecheckDependenciesFun(app, []);
        end

        % Button pushed function: PythonButton
        function PythonButtonPushed(app, event)
                %Select Download URL based on OS
                if ismac
                    web('https://www.python.org/downloads/mac-osx/')
                    return
                elseif isunix
                    web('https://www.python.org/downloads/source/')
                    return                
                elseif ispc
                    url = 'https://www.python.org/ftp/python/3.5.2/python-3.5.2-amd64-webinstall.exe';
                else
                    warndlg('Platform is not supported')
                    return
                end
                
                %Notify User
                approve = questdlg('This will download and install Python 3.5.2 from python.org',...
                    'Do you want to proceed?',...
                    'Yes', 'No', 'No');
                
                if strcmp(approve, 'Yes')
                    app.CurrentStausEditField.Value = 'Downloading Python Installer';
                    gitinstaler = websave(tempname, url);
                    
                    app.CurrentStausEditField.Value = 'Installing Python';
                    system(sprintf('%s /passive InstallLauncherAllUsers=0 PrependPath=1', gitinstaler));
                    
                    app.CurrentStausEditField.Value = '';
                end
                
                RecheckDependenciesFun(app, []);
        end

        % Button pushed function: PintButton
        function PintButtonPushed(app, event)
                app.CurrentStausEditField.Value = 'Installing Pint';
                system('pip install pint');
                
                app.CurrentStausEditField.Value = '';
                
                RecheckDependenciesFun(app, []);
        end

        % Button pushed function: NumpyButton
        function NumpyButtonPushed(app, event)
                app.CurrentStausEditField.Value = 'Installing Numpy';
                system('pip install numpy');
                
                app.CurrentStausEditField.Value = '';
                
                RecheckDependenciesFun(app, []);
        end

        % Button pushed function: DatamasterButton
        function DatamasterButtonPushed(app, event)
            %Save cd and move to the MATLAB folder
            currentDir = cd;
            [~, path] = system('echo %USERPROFILE%'); path = path(1:end-1);
            cd(fullfile(path,'Documents','MATLAB'));
            
            InstallPath = fullfile(path,'Documents','MATLAB','Datamaster');
            
            %Check if something exist in Datmaster's install Location
            install = true; %Flag to install/ not install Datamaster
            if isdir(InstallPath)
                %Notify User
                approve = questdlg(sprintf('Datamaster will overwrite all content at: %s', InstallPath),...
                    'Do you want to proceed?',...
                    'Yes, Overwrite the directory', 'No, I will download and setup Datmaster myself',...
                    'No, I will download and setup Datmaster myself');
                
                %Check Response
                if strcmp(approve, 'Yes, Overwrite the directory')
                    %Delete Old Contents
                    rmdir(InstallPath, 's');
                else
                    %Abort Install
                    install = false;
                end
            end
            
            if install
                %Clone Datmaster
                app.CurrentStausEditField.Value = 'Cloning into Datamaster';
                system('git clone https://github.com/awadell1/Datamaster.git Datamaster');
                
                %Add to path
                app.CurrentStausEditField.Value = 'Adding to Search Path';
                addpath(InstallPath);
                savepath
            end


            %Return to old cd
            app.CurrentStausEditField.Value = '';
            cd(currentDir);
            
            RecheckDependenciesFun(app);
        end

        % Button pushed function: InstallAllButton
        function InstallAllButtonPushed(app, event)
            %Trigger Each Button's Call Back
            GitButtonPushed(app);
            DatamasterButtonPushed(app);
            PythonButtonPushed(app);
            NumpyButtonPushed(app);
            PintButtonPushed(app);
        end

        % Button pushed function: CheckforUpdatesButton
        function CheckforUpdatesButtonPushed(app, event)
            %Move to the Datamaster folder
            savedCd = cd; cd(Datamaster.getPath);
            
            %Fetch updates from remote
            [success, ~] = system('git fetch');
            assert(success==0, 'Error fetching updates from remote');
            
            %Check Current Status
            [success, str] = system('git status');
            assert(success==0, 'Error reading git repo status');
                       
            %Get status
            status = regexpi(str, 'Your branch is behind ''(.+)'' by (\d+)', 'tokens');
            
            if ~isempty(status)
                %Behind remote branch
                nCommits = str2double(status{:}{2});
                remote = status{:}{1};
                
                qstr = sprintf('Behind %s by %d commits', remote, nCommits);
                button = questdlg(qstr, 'Update Datamaster?', 'Yes', 'No', 'No');
                if strcmp(button, 'Yes')
                    [exit, out] = system('git pull --force');
                    
                    if exit == 0 
                        msgbox('Datamaster is up-to-date')
                    else
                        errordlg(out, 'Error while updating')
                    end
                end
            else
               msgbox('Datamaster is up-to-date'); 
            end


            %Return to original directory
            cd(savedCd)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatmasterSetupUIFigure = uifigure;
            app.DatmasterSetupUIFigure.Name = 'Datmaster Setup';
            setAutoResize(app, app.DatmasterSetupUIFigure, true)
            app.CurrentStausEditFieldLabel = uilabel(app.DatmasterSetupUIFigure);
            app.CurrentStausEditFieldLabel.Text = 'Current Staus';
            app.CurrentStausEditField = uieditfield(app.DatmasterSetupUIFigure, 'text');
            app.TextArea = uitextarea(app.DatmasterSetupUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Green: dependency has been met'; 'Yellow: dependency has not been met'; 'Red: Other dependencies must be met first'};
            app.RecheckDependenciesButton = uibutton(app.DatmasterSetupUIFigure, 'push');
            app.RecheckDependenciesButton.ButtonPushedFcn = createCallbackFcn(app, @RecheckDependenciesFun, true);
            app.RecheckDependenciesButton.Text = 'Recheck Dependencies ';
            app.InstallAllButton = uibutton(app.DatmasterSetupUIFigure, 'push');
            app.InstallAllButton.ButtonPushedFcn = createCallbackFcn(app, @InstallAllButtonPushed, true);
            app.InstallAllButton.Text = 'Install All';
            app.GitButton = uibutton(app.DatmasterSetupUIFigure, 'push');
            app.GitButton.ButtonPushedFcn = createCallbackFcn(app, @GitButtonPushed, true);
            app.GitButton.Text = 'Git';
            app.GitLamp = uilamp(app.DatmasterSetupUIFigure);
            app.PythonButton = uibutton(app.DatmasterSetupUIFigure, 'push');
            app.PythonButton.ButtonPushedFcn = createCallbackFcn(app, @PythonButtonPushed, true);
            app.PythonButton.Text = 'Python';
            app.PythonLamp = uilamp(app.DatmasterSetupUIFigure);
            app.DatamasterButton = uibutton(app.DatmasterSetupUIFigure, 'push');
            app.DatamasterButton.ButtonPushedFcn = createCallbackFcn(app, @DatamasterButtonPushed, true);
            app.DatamasterButton.Text = 'Datamaster';
            app.DatmasterLamp = uilamp(app.DatmasterSetupUIFigure);
            app.PintButton = uibutton(app.DatmasterSetupUIFigure, 'push');
            app.PintButton.ButtonPushedFcn = createCallbackFcn(app, @PintButtonPushed, true);
            app.PintButton.Text = 'Pint';
            app.PintLamp = uilamp(app.DatmasterSetupUIFigure);
            app.NumpyButton = uibutton(app.DatmasterSetupUIFigure, 'push');
            app.NumpyButton.ButtonPushedFcn = createCallbackFcn(app, @NumpyButtonPushed, true);
            app.NumpyButton.Text = 'Numpy';
            app.NumpyLamp = uilamp(app.DatmasterSetupUIFigure);
            app.CheckforUpdatesButton = uibutton(app.DatmasterSetupUIFigure, 'push');
            app.CheckforUpdatesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckforUpdatesButtonPushed, true);
            app.CheckforUpdatesButton.Text = 'Check for Updates';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DatmasterPreference()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatmasterSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatmasterSetupUIFigure)
        end
    end
end